classdef enf4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ParmetrosGeneralesdeEntradaLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        balanceTab                     matlab.ui.container.Tab
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.EditField
        ButtonGroup3                   matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button2                        matlab.ui.control.RadioButton
        Button3                        matlab.ui.control.RadioButton
        Button5                        matlab.ui.control.Button
        ruidoTab                       matlab.ui.container.Tab
        CulgasSpinnerLabel             matlab.ui.control.Label
        CulgasSpinner                  matlab.ui.control.Spinner
        Button4                        matlab.ui.control.Button
        perdidasTab                    matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        EspaciolibreTab                matlab.ui.container.Tab
        FrecuenciaEditField_2Label     matlab.ui.control.Label
        FrecuenciaEditField_2          matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel        matlab.ui.control.Label
        DistanciaEditField             matlab.ui.control.NumericEditField
        CalcularButton                 matlab.ui.control.Button
        ResultLfs                      matlab.ui.control.NumericEditField
        DesapuntalamientoTab           matlab.ui.container.Tab
        DesapuenTxEditFieldLabel       matlab.ui.control.Label
        DesapuenTxEditField            matlab.ui.control.NumericEditField
        DesapuenRxEditFieldLabel       matlab.ui.control.Label
        DesapuenRxEditField            matlab.ui.control.NumericEditField
        AnchoHazenTxEditFieldLabel     matlab.ui.control.Label
        AnchoHazenTxEditField          matlab.ui.control.NumericEditField
        AnchoHazenRxEditFieldLabel     matlab.ui.control.Label
        AnchoHazenRxEditField          matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        ResultDesap                    matlab.ui.control.NumericEditField
        CalcularDesa                   matlab.ui.control.Button
        GasesTab                       matlab.ui.container.Tab
        PresinEditFieldLabel           matlab.ui.control.Label
        PresinEditField                matlab.ui.control.NumericEditField
        TemperaturaEditFieldLabel      matlab.ui.control.Label
        TemperaturaEditField           matlab.ui.control.NumericEditField
        DensidaddelaguaEditFieldLabel  matlab.ui.control.Label
        DensidaddelaguaEditField       matlab.ui.control.NumericEditField
        LonguituddetrayectoEditFieldLabel  matlab.ui.control.Label
        LonguituddetrayectoEditField   matlab.ui.control.NumericEditField
        Calcular_aten_gases            matlab.ui.control.Button
        ResultGases                    matlab.ui.control.NumericEditField
        FrecuenciaEditField_3Label     matlab.ui.control.Label
        FrecuenciaEditField_3          matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LibreButtonPushed(app, event)
            res=32.45+ 20*log(app.freq.Value)+20*log(app.radio_ob.Value)
            app.esp_libre.Text=num2str(res)+"dB";
        end

        % Callback function
        function LfsValueChanged(app, event)
            value = LibreButtonPushed.res;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            result = 32.45 + 20*log(app.FrecuenciaEditField_2 ...
                .Value) + 20*log(app.DistanciaEditField.Value);            
            app.ResultLfs.Value = result;
        end

        % Button pushed function: CalcularDesa
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
           
            app.ResultDesap.Value = result;
        end

        % Button pushed function: Calcular_aten_gases
        function Calcular_aten_gasesButtonPushed(app, event)
        try
            result = atenuacionGases(app.PresinEditField.Value,...
                app.TemperaturaEditField.Value, app.DensidaddelaguaEditField.Value,...
                app.LonguituddetrayectoEditField.Value,app.FrecuenciaEditField_3.Value);
            app.ResultGases.Value = result;
        catch
            f = app.UIFigure;
            uialert(f,'Datos invalidos','Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ParmetrosGeneralesdeEntradaLabel = uilabel(app.UIFigure);
            app.ParmetrosGeneralesdeEntradaLabel.Text = 'Parámetros Generales de Entrada';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.balanceTab = uitab(app.TabGroup);
            app.balanceTab.Title = 'balance';
            app.FrecuenciaEditFieldLabel = uilabel(app.balanceTab);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.balanceTab, 'text');
            app.ButtonGroup3 = uibuttongroup(app.balanceTab);
            app.ButtonGroup3.Title = 'Button Group3';
            app.Button = uiradiobutton(app.ButtonGroup3);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup3);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup3);
            app.Button3.Text = 'Button3';
            app.Button5 = uibutton(app.balanceTab, 'push');
            app.Button5.Text = 'Button5';
            app.ruidoTab = uitab(app.TabGroup);
            app.ruidoTab.Title = 'ruido';
            app.CulgasSpinnerLabel = uilabel(app.ruidoTab);
            app.CulgasSpinnerLabel.Text = '¿Cuálgas?';
            app.CulgasSpinner = uispinner(app.ruidoTab);
            app.Button4 = uibutton(app.ruidoTab, 'push');
            app.Button4.Text = 'Button4';
            app.perdidasTab = uitab(app.TabGroup);
            app.perdidasTab.Title = 'perdidas';
            app.TabGroup2 = uitabgroup(app.perdidasTab);
            app.EspaciolibreTab = uitab(app.TabGroup2);
            app.EspaciolibreTab.Title = 'Espacio libre';
            app.FrecuenciaEditField_2Label = uilabel(app.EspaciolibreTab);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.EspaciolibreTab, 'numeric');
            app.DistanciaEditFieldLabel = uilabel(app.EspaciolibreTab);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.EspaciolibreTab, 'numeric');
            app.CalcularButton = uibutton(app.EspaciolibreTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultLfs = uieditfield(app.EspaciolibreTab, 'numeric');
            app.DesapuntalamientoTab = uitab(app.TabGroup2);
            app.DesapuntalamientoTab.Title = 'Desapuntalamiento';
            app.DesapuenTxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.DesapuenTxEditFieldLabel.Text = 'Desapu. en Tx';
            app.DesapuenTxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.DesapuenRxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.DesapuenRxEditFieldLabel.Text = 'Desapu. en Rx';
            app.DesapuenRxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.AnchoHazenTxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.AnchoHazenTxEditFieldLabel.Text = 'Ancho Haz en Tx';
            app.AnchoHazenTxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.AnchoHazenRxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.AnchoHazenRxEditFieldLabel.Text = 'Ancho Haz en Rx';
            app.AnchoHazenRxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.Label = uilabel(app.DesapuntalamientoTab);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.DesapuntalamientoTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.DesapuntalamientoTab);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.DesapuntalamientoTab);
            app.Label_4.Text = '°';
            app.ResultDesap = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.CalcularDesa = uibutton(app.DesapuntalamientoTab, 'push');
            app.CalcularDesa.ButtonPushedFcn = createCallbackFcn(app, @CalcularDesaButtonPushed, true);
            app.CalcularDesa.Text = 'Calcular';
            app.GasesTab = uitab(app.TabGroup2);
            app.GasesTab.Title = 'Gases';
            app.PresinEditFieldLabel = uilabel(app.GasesTab);
            app.PresinEditFieldLabel.Text = 'Presión';
            app.PresinEditField = uieditfield(app.GasesTab, 'numeric');
            app.TemperaturaEditFieldLabel = uilabel(app.GasesTab);
            app.TemperaturaEditFieldLabel.Text = 'Temperatura';
            app.TemperaturaEditField = uieditfield(app.GasesTab, 'numeric');
            app.DensidaddelaguaEditFieldLabel = uilabel(app.GasesTab);
            app.DensidaddelaguaEditFieldLabel.Text = 'Densidad del agua';
            app.DensidaddelaguaEditField = uieditfield(app.GasesTab, 'numeric');
            app.LonguituddetrayectoEditFieldLabel = uilabel(app.GasesTab);
            app.LonguituddetrayectoEditFieldLabel.Text = 'Longuitud de trayecto';
            app.LonguituddetrayectoEditField = uieditfield(app.GasesTab, 'numeric');
            app.Calcular_aten_gases = uibutton(app.GasesTab, 'push');
            app.Calcular_aten_gases.ButtonPushedFcn = createCallbackFcn(app, @Calcular_aten_gasesButtonPushed, true);
            app.Calcular_aten_gases.Text = 'Calcular';
            app.ResultGases = uieditfield(app.GasesTab, 'numeric');
            app.FrecuenciaEditField_3Label = uilabel(app.GasesTab);
            app.FrecuenciaEditField_3Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_3 = uieditfield(app.GasesTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enf4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end