classdef interfazDiv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsMedicalSurveyUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        EscribalosvaloresLabel         matlab.ui.control.Label
        FrecuenciaEditFieldLabel       matlab.ui.control.Label
        FrecuenciaEditField            matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel        matlab.ui.control.Label
        DistanciaEditField             matlab.ui.control.NumericEditField
        GHzLabel                       matlab.ui.control.Label
        KmLabel                        matlab.ui.control.Label
        FijarDatosButton               matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        EspaciolibreTab                matlab.ui.container.Tab
        CalcularButton                 matlab.ui.control.Button
        ResultLfs                      matlab.ui.control.NumericEditField
        dBLabel                        matlab.ui.control.Label
        DesapuntalamientoTab           matlab.ui.container.Tab
        DesapuenTxEditFieldLabel       matlab.ui.control.Label
        DesapuenTxEditField            matlab.ui.control.NumericEditField
        DesapuenRxEditFieldLabel       matlab.ui.control.Label
        DesapuenRxEditField            matlab.ui.control.NumericEditField
        AnchoHazenTxEditFieldLabel     matlab.ui.control.Label
        AnchoHazenTxEditField          matlab.ui.control.NumericEditField
        AnchoHazenRxEditFieldLabel     matlab.ui.control.Label
        AnchoHazenRxEditField          matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        ResultDesap                    matlab.ui.control.NumericEditField
        CalcularDesa                   matlab.ui.control.Button
        dBLabel_2                      matlab.ui.control.Label
        GasesTab                       matlab.ui.container.Tab
        PresinEditFieldLabel           matlab.ui.control.Label
        PresinEditField                matlab.ui.control.NumericEditField
        TemperaturaEditFieldLabel      matlab.ui.control.Label
        TemperaturaEditField           matlab.ui.control.NumericEditField
        DensidaddelaguaEditFieldLabel  matlab.ui.control.Label
        DensidaddelaguaEditField       matlab.ui.control.NumericEditField
        Calcular_aten_gases            matlab.ui.control.Button
        ResultGases                    matlab.ui.control.NumericEditField
        hPaLabel                       matlab.ui.control.Label
        gm3Label                       matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        LluviaTab                      matlab.ui.container.Tab
        OtrasTab                       matlab.ui.container.Tab
        TotalTab                       matlab.ui.container.Tab
        CalcularTotal                  matlab.ui.control.Button
        ResultTotal                    matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data % Patient data
    end
    
    methods (Access = private)
        
        function enableForm(app)
            % Enable form elements
            app.NameEditField.Enable = 'on';
            app.AgeEditField.Enable = 'on';
            app.GenderDropDown.Enable = 'on';
            app.HealthStatusDropDown.Enable = 'on';
            app.SmokerCheckBox.Enable = 'on';

           app.NameEditFieldLabel.Enable = 'on';            
            app.AgeEditFieldLabel.Enable = 'on';
           app.GenderDropDownLabel.Enable = 'on';
            app.HealthStatusDropDownLabel.Enable = 'on';
        end
        
        function disableForm(app)
            % Set form fields to default values
            app.NameEditField.Value = '';
            app.AgeEditField.Value = 0;
            app.GenderDropDown.Value = 'Male';
            app.HealthStatusDropDown.Value = 'Excellent';
            app.SmokerCheckBox.Value = false;
            
            % Disable form fields
            app.NameEditField.Enable = 'off';
            app.AgeEditField.Enable = 'off';
            app.GenderDropDown.Enable = 'off';
            app.HealthStatusDropDown.Enable = 'off';
            app.SmokerCheckBox.Enable = 'off'; 
                        
            app.NameEditFieldLabel.Enable = 'off';            
            app.AgeEditFieldLabel.Enable = 'off';
            app.GenderDropDownLabel.Enable = 'off';
            app.HealthStatusDropDownLabel.Enable = 'off';
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsMedicalSurveyUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {385, 385};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CalcularButton, 
        % PatientsMedicalSurveyUIFigure
        function CalcularButtonPushed(app, event)

           % if app.FrecuenciaEditField.Value > 50
                %f = app.UIFigure;
                %uialert(f,'Valor de frecuencia invalida, deber ser menor de 50','Error');
            %end
            result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.ResultLfs.Value = result;
        end

        % Button pushed function: CalcularDesa
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
           
            app.ResultDesap.Value = result;
        end

        % Button pushed function: Calcular_aten_gases
        function Calcular_aten_gasesButtonPushed(app, event)
            try
            result = atenuacionGases(app.PresinEditField.Value,...
                app.TemperaturaEditField.Value, app.DensidaddelaguaEditField.Value,...
                app.DistanciaEditField.Value,app.FrecuenciaEditField.Value);
            app.ResultGases.Value = result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: FijarDatosButton
        function FijarDatosButtonPushed(app, event)
            if app.FrecuenciaEditField.Value < 56 && (app.FrecuenciaEditField.Value ~= 0 || app.DistanciaEditField.Value ~= 0 )
                app.TabGroup2.Visible = 'on';
            elseif app.FrecuenciaEditField.Value >= 56
                    msgbox('Frecuncia invalida, debe ingresar una frecuncia menor de 56GHz', 'Error','error');
                    app.FrecuenciaEditField.Value = 0;                
                elseif app.FrecuenciaEditField.Value == 0 || app.DistanciaEditField.Value == 0 
                    msgbox('Valores en cero', 'Error','error');
               end             
            
        end

        % Button pushed function: CalcularTotal
        function CalcularTotalButtonPushed(app, event)
            app.ResultTotal.Value = app.ResultLfs.Value + app.ResultDesap.Value...
                + app.ResultGases.Value;
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)
            selectedTab = app.TabGroup2.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsMedicalSurveyUIFigure = uifigure('Visible', 'off');
            app.PatientsMedicalSurveyUIFigure.Name = 'SIMULADOR SATELITAL';
            app.PatientsMedicalSurveyUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.PatientsMedicalSurveyUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsMedicalSurveyUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'VARIABLES DE INICIO';
            app.EscribalosvaloresLabel = uilabel(app.LeftPanel);
            app.EscribalosvaloresLabel.Text = 'Escriba los valores:';
            app.FrecuenciaEditFieldLabel = uilabel(app.LeftPanel);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanciaEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.GHzLabel = uilabel(app.LeftPanel);
            app.GHzLabel.Text = '  GHz';
            app.KmLabel = uilabel(app.LeftPanel);
            app.KmLabel.Text = '  Km';
            app.FijarDatosButton = uibutton(app.LeftPanel, 'push');
            app.FijarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @FijarDatosButtonPushed, true);
            app.FijarDatosButton.Text = 'Fijar Datos';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.RightPanel);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.EspaciolibreTab = uitab(app.TabGroup2);
            app.EspaciolibreTab.Title = 'Espacio libre';
            app.CalcularButton = uibutton(app.EspaciolibreTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultLfs = uieditfield(app.EspaciolibreTab, 'numeric');
            app.dBLabel = uilabel(app.EspaciolibreTab);
            app.dBLabel.Text = ' dB';
            app.DesapuntalamientoTab = uitab(app.TabGroup2);
            app.DesapuntalamientoTab.Title = 'Desapuntalamiento';
            app.DesapuenTxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.DesapuenTxEditFieldLabel.Text = 'Desapu. en Tx';
            app.DesapuenTxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.DesapuenRxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.DesapuenRxEditFieldLabel.Text = 'Desapu. en Rx';
            app.DesapuenRxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.AnchoHazenTxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.AnchoHazenTxEditFieldLabel.Text = 'Ancho Haz en Tx';
            app.AnchoHazenTxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.AnchoHazenRxEditFieldLabel = uilabel(app.DesapuntalamientoTab);
            app.AnchoHazenRxEditFieldLabel.Text = 'Ancho Haz en Rx';
            app.AnchoHazenRxEditField = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.Label = uilabel(app.DesapuntalamientoTab);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.DesapuntalamientoTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.DesapuntalamientoTab);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.DesapuntalamientoTab);
            app.Label_4.Text = '°';
            app.ResultDesap = uieditfield(app.DesapuntalamientoTab, 'numeric');
            app.CalcularDesa = uibutton(app.DesapuntalamientoTab, 'push');
            app.CalcularDesa.ButtonPushedFcn = createCallbackFcn(app, @CalcularDesaButtonPushed, true);
            app.CalcularDesa.Text = 'Calcular';
            app.dBLabel_2 = uilabel(app.DesapuntalamientoTab);
            app.dBLabel_2.Text = ' dB';
            app.GasesTab = uitab(app.TabGroup2);
            app.GasesTab.Title = 'Gases';
            app.PresinEditFieldLabel = uilabel(app.GasesTab);
            app.PresinEditFieldLabel.Text = 'Presión';
            app.PresinEditField = uieditfield(app.GasesTab, 'numeric');
            app.TemperaturaEditFieldLabel = uilabel(app.GasesTab);
            app.TemperaturaEditFieldLabel.Text = 'Temperatura';
            app.TemperaturaEditField = uieditfield(app.GasesTab, 'numeric');
            app.DensidaddelaguaEditFieldLabel = uilabel(app.GasesTab);
            app.DensidaddelaguaEditFieldLabel.Text = 'Densidad del agua';
            app.DensidaddelaguaEditField = uieditfield(app.GasesTab, 'numeric');
            app.Calcular_aten_gases = uibutton(app.GasesTab, 'push');
            app.Calcular_aten_gases.ButtonPushedFcn = createCallbackFcn(app, @Calcular_aten_gasesButtonPushed, true);
            app.Calcular_aten_gases.Text = 'Calcular';
            app.ResultGases = uieditfield(app.GasesTab, 'numeric');
            app.hPaLabel = uilabel(app.GasesTab);
            app.hPaLabel.Text = '  hPa';
            app.gm3Label = uilabel(app.GasesTab);
            app.gm3Label.Text = '  g/m^3';
            app.CLabel = uilabel(app.GasesTab);
            app.CLabel.Text = '  °C';
            app.dBLabel_3 = uilabel(app.GasesTab);
            app.dBLabel_3.Text = '  dB';
            app.LluviaTab = uitab(app.TabGroup2);
            app.LluviaTab.Title = 'Lluvia';
            app.OtrasTab = uitab(app.TabGroup2);
            app.OtrasTab.Title = 'Otras';
            app.TotalTab = uitab(app.TabGroup2);
            app.TotalTab.Title = 'Total';
            app.CalcularTotal = uibutton(app.TotalTab, 'push');
            app.CalcularTotal.ButtonPushedFcn = createCallbackFcn(app, @CalcularTotalButtonPushed, true);
            app.CalcularTotal.Text = 'Calcular';
            app.ResultTotal = uieditfield(app.TotalTab, 'numeric');
            % Show the figure after all components are created
            app.PatientsMedicalSurveyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazDiv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsMedicalSurveyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsMedicalSurveyUIFigure)
        end
    end
end