classdef interfaz_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsMedicalSurveyUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ValoresdeReferenciaLabel        matlab.ui.control.Label
        FijarDatosButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        LatitudEditFieldLabel           matlab.ui.control.Label
        LatitudEditField                matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        ElevacionEditFieldLabel         matlab.ui.control.Label
        ElevacionEditField              matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        BandaDropDownLabel              matlab.ui.control.Label
        BandaDropDown                   matlab.ui.control.DropDown
        AlturahsEditFieldLabel          matlab.ui.control.Label
        AlturahsEditField               matlab.ui.control.NumericEditField
        KmLabel_2                       matlab.ui.control.Label
        DiametroETEditFieldLabel        matlab.ui.control.Label
        DiametroETEditField             matlab.ui.control.NumericEditField
        nETEditFieldLabel               matlab.ui.control.Label
        nETEditField                    matlab.ui.control.NumericEditField
        RightPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        PerdidasTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BasicasTab                      matlab.ui.container.Tab
        CalcularButton_2                matlab.ui.control.Button
        libre                           matlab.ui.control.NumericEditField
        dBLabel_4                       matlab.ui.control.Label
        Lfs_downEditFieldLabel          matlab.ui.control.Label
        Lfs_downEditField               matlab.ui.control.NumericEditField
        Lfs_upLabel                     matlab.ui.control.Label
        dBLabel_11                      matlab.ui.control.Label
        DistanciaEditFieldLabel         matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        KmLabel                         matlab.ui.control.Label
        DesapuntamientoTab              matlab.ui.container.Tab
        DesapenTxEditFieldLabel         matlab.ui.control.Label
        DesapenTxEditField              matlab.ui.control.NumericEditField
        CalcularButton_3                matlab.ui.control.Button
        DesapenRxEditFieldLabel         matlab.ui.control.Label
        DesapenRxEditField              matlab.ui.control.NumericEditField
        Ancho3dBEditFieldLabel          matlab.ui.control.Label
        Ancho3dBEditField               matlab.ui.control.NumericEditField
        desap                           matlab.ui.control.NumericEditField
        dBLabel_5                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        GasesTab_2                      matlab.ui.container.Tab
        CalcularButton_4                matlab.ui.control.Button
        pgases                          matlab.ui.control.NumericEditField
        dBLabel_6                       matlab.ui.control.Label
        DensidaddelAguaEditFieldLabel   matlab.ui.control.Label
        DensidadEditField               matlab.ui.control.NumericEditField
        gm3Label_2                      matlab.ui.control.Label
        UplinkEditFieldLabel            matlab.ui.control.Label
        GasesUplinkEditField            matlab.ui.control.NumericEditField
        DownlinkLabel_2                 matlab.ui.control.Label
        dBLabel_12                      matlab.ui.control.Label
        LluviaTab_2                     matlab.ui.container.Tab
        Label_8                         matlab.ui.control.Label
        mmhLabel                        matlab.ui.control.Label
        CalcularButton_6                matlab.ui.control.Button
        downplluvia                     matlab.ui.control.NumericEditField
        dBLabel_8                       matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        FigH0Button                     matlab.ui.control.Button
        PBajdaLabel                     matlab.ui.control.Label
        Up_plluvia                      matlab.ui.control.NumericEditField
        dBLabel_10                      matlab.ui.control.Label
        PSubidaLabel                    matlab.ui.control.Label
        ITUP837Button                   matlab.ui.control.Button
        h0EditFieldLabel                matlab.ui.control.Label
        h0EditField                     matlab.ui.control.NumericEditField
        IndiponibilidadpEditFieldLabel  matlab.ui.control.Label
        IndiponibilidadpEditField       matlab.ui.control.NumericEditField
        IntensidadLluviaEditFieldLabel  matlab.ui.control.Label
        IntensidadLluviaEditField       matlab.ui.control.NumericEditField
        LongitudEditFieldLabel          matlab.ui.control.Label
        LongitudEditField               matlab.ui.control.NumericEditField
        PolarizacinDropDownLabel        matlab.ui.control.Label
        PolarizacinDropDown             matlab.ui.control.DropDown
        OtrasPerdTab                    matlab.ui.container.Tab
        ResultOtrasper                  matlab.ui.control.NumericEditField
        CalcularOtrasper                matlab.ui.control.Button
        CentelleoLabel                  matlab.ui.control.Label
        UplinkEditFieldLabel_2          matlab.ui.control.Label
        CentelleoUplinkEditField        matlab.ui.control.NumericEditField
        DownlinkLabel_3                 matlab.ui.control.Label
        TotalTab_2                      matlab.ui.container.Tab
        CalcularButton_5                matlab.ui.control.Button
        ptotal                          matlab.ui.control.NumericEditField
        dBLabel_7                       matlab.ui.control.Label
        UplinkEditFieldLabel_3          matlab.ui.control.Label
        UplinkTotalEditField            matlab.ui.control.NumericEditField
        DownlinkLabel_4                 matlab.ui.control.Label
        RuidoTab                        matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        AntenaTab                       matlab.ui.container.Tab
        Switch                          matlab.ui.control.Switch
        CalcularButton_8                matlab.ui.control.Button
        res_antena                      matlab.ui.control.NumericEditField
        TgEditFieldLabel                matlab.ui.control.Label
        TgEditField                     matlab.ui.control.NumericEditField
        GlsEditFieldLabel               matlab.ui.control.Label
        GlsEditField                    matlab.ui.control.NumericEditField
        TskyEditFieldLabel              matlab.ui.control.Label
        TskyEditField                   matlab.ui.control.NumericEditField
        TatmEditFieldLabel              matlab.ui.control.Label
        TatmEditField                   matlab.ui.control.NumericEditField
        TwacoploEditFieldLabel          matlab.ui.control.Label
        TwacoploEditField               matlab.ui.control.NumericEditField
        wacoploEditFieldLabel           matlab.ui.control.Label
        wacoploEditField                matlab.ui.control.NumericEditField
        ReceptorTab                     matlab.ui.container.Tab
        ValoresLabel                    matlab.ui.control.Label
        Valores_g                       matlab.ui.control.EditField
        EjsillggLabel                   matlab.ui.control.Label
        CalcularButton_7                matlab.ui.control.Button
        ResultadoRuido                  matlab.ui.control.NumericEditField
        KLabel                          matlab.ui.control.Label
        OrdenEditFieldLabel             matlab.ui.control.Label
        Orden                           matlab.ui.control.EditField
        TemperaturaEditField_3Label     matlab.ui.control.Label
        TemperaturaRuido                matlab.ui.control.EditField
        Siaplica2sinoescribir0Label     matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Ej12151Label                    matlab.ui.control.Label
        TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel  matlab.ui.control.Label
        AplicacuandoenelordenexistegLabel  matlab.ui.control.Label
        SiesgelvaloresenglomismoparaLNohacerconversionesLabel  matlab.ui.control.Label
        RuidoSistemaTab                 matlab.ui.container.Tab
        TemperaturadeBrilloEditFieldLabel  matlab.ui.control.Label
        TemperaturadeBrilloEditField    matlab.ui.control.NumericEditField
        CalcularButton_9                matlab.ui.control.Button
        EnlacedeSubidaEditFieldLabel    matlab.ui.control.Label
        EnlacedeSubidaEditField         matlab.ui.control.NumericEditField
        EnlacedeBajadaEditFieldLabel    matlab.ui.control.Label
        EnlacedeBajadaEditField         matlab.ui.control.NumericEditField
        FigTempBrillButton              matlab.ui.control.Button
        BackoffTab                      matlab.ui.container.Tab
        Switch_2                        matlab.ui.control.Switch
        CalcularButton_10               matlab.ui.control.Button
        PireinEditFieldLabel            matlab.ui.control.Label
        PireinEditField                 matlab.ui.control.NumericEditField
        PireoutEditFieldLabel           matlab.ui.control.Label
        PireoutEditField                matlab.ui.control.NumericEditField
        IBEditFieldLabel                matlab.ui.control.Label
        IBEditField                     matlab.ui.control.NumericEditField
        OBEditFieldLabel                matlab.ui.control.Label
        OBEditField                     matlab.ui.control.NumericEditField
        pireinsat                       matlab.ui.control.NumericEditField
        pireoutsat                      matlab.ui.control.NumericEditField
        PireinsatLabel                  matlab.ui.control.Label
        PireoutsatLabel                 matlab.ui.control.Label
        DeEntradaLabel                  matlab.ui.control.Label
        dBmLabel                        matlab.ui.control.Label
        dBmLabel_2                      matlab.ui.control.Label
        ConPireptxgtxLabel              matlab.ui.control.Label
        GTTab                           matlab.ui.container.Tab
        CalcularGT                      matlab.ui.control.Button
        ResultGT                        matlab.ui.control.NumericEditField
        dBLabel_9                       matlab.ui.control.Label
        CNTab                           matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        UpLinkTab                       matlab.ui.container.Tab
        CalcularCNUp                    matlab.ui.control.Button
        ResultCnUp                      matlab.ui.control.NumericEditField
        PotenciaEditFieldLabel          matlab.ui.control.Label
        PotenciaEditField               matlab.ui.control.NumericEditField
        OBEditField_2Label              matlab.ui.control.Label
        OBEditField_2                   matlab.ui.control.NumericEditField
        GTsatEditFieldLabel             matlab.ui.control.Label
        GTsatEditField                  matlab.ui.control.NumericEditField
        dBwLabel                        matlab.ui.control.Label
        nEditFieldLabel                 matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        DiametroEditFieldLabel          matlab.ui.control.Label
        DiametroEditField               matlab.ui.control.NumericEditField
        dBLabel_13                      matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        BwsistEditFieldLabel            matlab.ui.control.Label
        BwsistEditField                 matlab.ui.control.NumericEditField
        AccesomultipleSwitchLabel       matlab.ui.control.Label
        AccesomultipleSwitch            matlab.ui.control.Switch
        TipoPotenciaSwitchLabel         matlab.ui.control.Label
        TipoPotenciaSwitch              matlab.ui.control.Switch
        DownlinkTab                     matlab.ui.container.Tab
        CalcularCnD                     matlab.ui.control.Button
        ResultCnDField                  matlab.ui.control.NumericEditField
        dBwLabel_2                      matlab.ui.control.Label
        dBLabel_14                      matlab.ui.control.Label
        PotenciaEditField_2Label        matlab.ui.control.Label
        Potencia_dEditField             matlab.ui.control.NumericEditField
        OBEditField_3Label              matlab.ui.control.Label
        OB_dEditField                   matlab.ui.control.NumericEditField
        TipoPotenciaSwitch_2Label       matlab.ui.control.Label
        TipoPotenciaSwitch_2            matlab.ui.control.Switch
        TotalTab_3                      matlab.ui.container.Tab
        ResultCnTEditField              matlab.ui.control.NumericEditField
        CalcularCnToButton              matlab.ui.control.Button
        CapacidadTab                    matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        RbTab                           matlab.ui.container.Tab
        dBLabel_15                      matlab.ui.control.Label
        dBLabel_16                      matlab.ui.control.Label
        CalcularRB                      matlab.ui.control.Button
        ResRb                           matlab.ui.control.NumericEditField
        Label_15                        matlab.ui.control.Label
        VelocidaddelEnlaceLabel         matlab.ui.control.Label
        RelacinBEditFieldLabel          matlab.ui.control.Label
        RelacinBEditField               matlab.ui.control.NumericEditField
        RelacinCWEditFieldLabel         matlab.ui.control.Label
        RelacinCWEditField              matlab.ui.control.NumericEditField
        RpTab                           matlab.ui.container.Tab
        dBLabel_17                      matlab.ui.control.Label
        dBLabel_18                      matlab.ui.control.Label
        CalcularRp                      matlab.ui.control.Button
        ResRp                           matlab.ui.control.NumericEditField
        Label_16                        matlab.ui.control.Label
        EbNoEditFieldLabel              matlab.ui.control.Label
        EbNoEditField                   matlab.ui.control.NumericEditField
        MLabel                          matlab.ui.control.Label
        MargenDesvEditField             matlab.ui.control.NumericEditField
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data % Patient data
        %-----------------VARIABLES GLOBALES-----------------------
        Resultlfs
        ResultDesapun
        ResultGases
        ResultLluvia
        ResultOtrasPer
        ResultPerdTot    
        ResultAnt
        ResultTrx
        ResultRuidoTot
        ResultGT_sat
        ResultGT_et
        %banda = app.BandaDropDown.Value;
        fr_up
        fr_d
        ganancia
        ganancia_et
        pire_d
    end
    
    methods (Access = private)
        
        function enableForm(app)
            % Enable form elements
            app.NameEditField.Enable = 'on';
            app.AgeEditField.Enable = 'on';
            app.GenderDropDown.Enable = 'on';
            app.HealthStatusDropDown.Enable = 'on';
            app.SmokerCheckBox.Enable = 'on';

           app.NameEditFieldLabel.Enable = 'on';            
            app.AgeEditFieldLabel.Enable = 'on';
           app.GenderDropDownLabel.Enable = 'on';
            app.HealthStatusDropDownLabel.Enable = 'on';
        end
        
        function disableForm(app)
            % Set form fields to default values
            app.NameEditField.Value = '';
            app.AgeEditField.Value = 0;
            app.GenderDropDown.Value = 'Male';
            app.HealthStatusDropDown.Value = 'Excellent';
            app.SmokerCheckBox.Value = false;
            
            % Disable form fields
            app.NameEditField.Enable = 'off';
            app.AgeEditField.Enable = 'off';
            app.GenderDropDown.Enable = 'off';
            app.HealthStatusDropDown.Enable = 'off';
            app.SmokerCheckBox.Enable = 'off'; 
                        
            app.NameEditFieldLabel.Enable = 'off';            
            app.AgeEditFieldLabel.Enable = 'off';
            app.GenderDropDownLabel.Enable = 'off';
            app.HealthStatusDropDownLabel.Enable = 'off';
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsMedicalSurveyUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {443, 443};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: PatientsMedicalSurveyUIFigure
        function CalcularButtonPushed(app, event)

           % if app.FrecuenciaEditField.Value > 50
                %f = app.UIFigure;
                %uialert(f,'Valor de frecuencia invalida, deber ser menor de 50','Error');
            %end
           % result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
               % + 20*log10(app.DistanciaEditField.Value);            
            %app.ResultLfs.Value = result;
        end

        % Callback function
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
           
            app.ResultDesap.Value = result;
            app.ResultDesapun = result;
        end

        % Callback function
        function Calcular_aten_gasesButtonPushed(app, event)
            try
            result = atenuacionGases(app.PresinEditField.Value,...
                app.TemperaturaEditField.Value, app.DensidaddelaguaEditField.Value,...
                app.DistanciaEditField.Value,app.FrecuenciaEditField.Value);
            app.ResultGases.Value = result;
            app.ResultGases=result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: FijarDatosButton
        function FijarDatosButtonPushed(app, event)
            if app.FrecuenciaEditField.Value < 56 && (app.FrecuenciaEditField.Value ~= 0 || app.DistanciaEditField.Value ~= 0 )
                app.TabGroup3.Visible = 'on';
            elseif app.FrecuenciaEditField.Value >= 56
                    msgbox('Frecuncia invalida, debe ingresar una frecuncia menor de 56GHz', 'Error','error');
                    app.FrecuenciaEditField.Value = 0;                
                elseif app.FrecuenciaEditField.Value == 0 || app.DistanciaEditField.Value == 0 
                    msgbox('Valores en cero', 'Error','error');
               end             
            
        end

        % Callback function
        function CalcularTotalButtonPushed(app, event)
            app.ResultTotal.Value = app.ResultLfs.Value + app.ResultDesap.Value...
                + app.ResultGases.Value;
            app.ResultPerdTot = app.ResultTotal.Value;
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            %result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
                %+ 20*log10(app.DistanciaEditField.Value);    
                app.fr_up
            result = 32.45 + 20*log10(app.fr_up*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.libre.Value = result;
            app.Lfs_downEditField.Value = 32.45 + 20*log10(app.fr_d*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.libre.Value = result;
            app.Resultlfs = result;           
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            result = 12*(app.DesapenTxEditField.Value/app.Ancho3dBEditField.Value)^2 + ...
            12*(app.DesapenRxEditField.Value/app.Ancho3dBEditField.Value)^2;
           
            app.desap.Value = result;
            app.ResultDesapun = result;           
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            try
                app.GasesUplinkEditField.Value = atenuacionGasesPT( app.AlturahsEditField.Value,...
                    app.DensidadEditField.Value,app.ElevacionEditField.Value, app.fr_up);
            result =atenuacionGasesPT(app.AlturahsEditField.Value,...
                    app.DensidadEditField.Value,app.ElevacionEditField.Value, app.fr_d);
            app.pgases.Value = result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: CalcularButton_5
        function CalcularButton_5Pushed(app, event)
            %app.ptotal.Value = app.libre.Value + app.desap.Value...
               % + app.pgases.Value+app.downplluvia.Value;

                a=app.libre.Value;
                b=app.desap.Value;
                c=app.pgases.Value;
                d=app.downplluvia.Value;
                %agregar nuevas perdidas
                app.ptotal.Value=10*log10( 10^(a/10) +10^(b/10) +10^(c/10) +10^(d/10) );
                a=app.Lfs_downEditField.Value;                
                c=app.GasesUplinkEditField.Value;
                d=app.Up_plluvia.Value;
                app.UplinkTotalEditField.Value = 10*log10( 10^(a/10) +10^(c/10) +10^(d/10) );
        end

        % Button pushed function: CalcularButton_6
        function CalcularButton_6Pushed(app, event)
            
            k= [0.02386 0.02455; 0.03738 0.04126; 0.09164 0.09611; 0.3070 0.2943];
            a = [1.1825 1.1216; 1.1396 1.0646; 1.0568 0.993; 0.3070 0.1149];
            if app.PolarizacinDropDown.Value == "Horizontal"
                if app.BandaDropDown.Value == "Ku"
                    vlrKup = k(2,1);
                    vlrAup= a(2,1);
                    vlrKd = k(1,1);
                    vlrAd = a(1,1);
                elseif app.BandaDropDown.Value == "Ka"
                    vlrKup = k(4,1);
                    vlrAup= a(4,1);
                    vlrKd = k(3,1);
                    vlrAd = a(3,1);
                else
                    vlrKup = 0;
                    vlrAup= 0;
                    vlrKd = 0;
                    vlrAd = 0;
                end
            else
                if app.BandaDropDown.Value == "Ku"
                    vlrKup = k(2,2);
                    vlrAup= a(2,2);
                    vlrKd = k(1,2);
                    vlrAd = a(1,2);
                elseif app.BandaDropDown.Value == "Ka"
                    vlrKup = k(4,2);
                    vlrAup= a(4,2);
                    vlrKd = k(3,2);
                    vlrAd = a(3,2);
                else
                    vlrKup = 0;
                    vlrAup= 0;
                    vlrKd = 0;
                    vlrAd = 0;
                end            
            end             
            app.Up_plluvia.Value = perdidas_lluvia(app.fr_up,app.LatitudEditField.Value,...
                app.LongitudEditField.Value, app.ElevacionEditField.Value, ...
             app.IntensidadLluviaEditField.Value, vlrKup, vlrAup,...
             app.AlturahsEditField.Value, app.h0EditField.Value, app.IndiponibilidadpEditField.Value);
         
            app.downplluvia.Value=perdidas_lluvia(app.fr_d,app.LatitudEditField.Value,...
                app.LongitudEditField.Value, app.ElevacionEditField.Value, ...
             app.IntensidadLluviaEditField.Value, vlrKd, vlrAd,...
             app.AlturahsEditField.Value, app.h0EditField.Value, app.IndiponibilidadpEditField.Value);
         app.ResultLluvia = app.downplluvia.Value;
        end

        % Button pushed function: CalcularOtrasper
        function CalcularOtrasperButtonPushed(app, event)
            app.CentelleoUplinkEditField.Value = centelleo(app.fr_up,...
                app.ElevacionEditField.Value, app.LatitudEditField.Value);
            app.ResultOtrasper.Value = centelleo(app.fr_d,...
                app.ElevacionEditField.Value, app.LatitudEditField.Value);
            app.ResultOtrasPer = app.ResultOtrasper.Value;
        end

        % Button pushed function: CalcularButton_7
        function CalcularButton_7Pushed(app, event)
        array_tipo=string(split(app.Orden.Value))    
        array_g=double(string(split(app.Valores_g.Value)));
        %array_g = string(split(app.Valores_g.Value));
        
        array_temp=double(string(split(app.TemperaturaRuido.Value)));
   
            ruido_total=ruido_recepcion(array_g, array_tipo, array_temp);
            %app.ganancia = ganancia_sistema(array_g,array_tipo);
            app.ResultadoRuido.Value=ruido_total;
            app.ResultTrx = ruido_total;
        end

        % Button pushed function: CalcularButton_8
        function CalcularButton_8Pushed(app, event)
            tipo=app.Switch.Value;
            Tg=app.TgEditField.Value;
            gls=app.GlsEditField.Value;
            Ts=app.TskyEditField.Value;
            Tat=app.TatmEditField.Value;
            ag=app.pgases.Value/10;
            ag= 10^(ag/10)
            al=app.downplluvia.Value;
            al =10^(al/10)
            Tw=app.TwacoploEditField.Value;
            w=app.wacoploEditField.Value;
            res_antena=ruido_antena(tipo,Tg,gls,Ts,Tat,ag,al,Tw,w);
            app.res_antena.Value=res_antena;
            
            app.ResultAnt = res_antena;
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalcularButton_9
        function CalcularButton_9Pushed(app, event)
            ruido_antena=app.res_antena.Value;
            ruido_recepcion=app.ResultadoRuido.Value;
            temperaratura_brillo=app.TemperaturadeBrilloEditField.Value
            [temperatura_up temperatura_down]=ruido_total(ruido_antena, ruido_recepcion, temperaratura_brillo)
            app.EnlacedeSubidaEditField.Value=temperatura_up;
            app.EnlacedeBajadaEditField.Value=temperatura_down;
            
        end

        % Button pushed function: CalcularButton_10
        function CalcularButton_10Pushed(app, event)
        if string(app.Switch_2.Value)=="TDMA"
            %saturacion se habilita
            app.pireinsat.Value=app.PireinEditField.Value+app.IBEditField.Value;
            app.pireoutsat.Value=app.PireoutEditField.Value+app.OBEditField.Value;
        else
            %saturacion no permitida
           app.pireinsat.Value=app.PireinEditField.Value;
            app.pireoutsat.Value=app.PireoutEditField.Value;
        end
        
        end

        % Button pushed function: CalcularGT
        function CalcularGTPushed(app, event)
            app.ganancia_et = ganancia_antena(app.fr_d, app.nETEditField.Value, app.DiametroETEditField.Value);
            app.ResultGT_et = g_tET(app.ganancia_et,app.EnlacedeSubidaEditField.Value);
            app.ResultGT.Value = app.ResultGT_et;                       
            
        end

        % Button pushed function: CalcularCNUp
        function CalcularCNUpButtonPushed(app, event)
            tipo_acceso = app.AccesomultipleSwitch.Value;
            tipo_pote = app.TipoPotenciaSwitch.Value;
                gan_ant_sate = ganancia_antena(app.fr_up,app.nEditField.Value,app.DiametroEditField.Value)
            if  strcmp( tipo_acceso, 'FDMA')
                if strcmp(tipo_pote, 'Saturacion')   
                    pire = app.PotenciaEditField.Value-app.OBEditField_2.Value+gan_ant_sate;
                    a = "F - S"
                else
                    pire = app.PotenciaEditField.Value+gan_ant_sate;
                    a = "F - L"
                end
            else
                if strcmp(tipo_pote, 'Saturacion')
                    pire = app.PotenciaEditField.Value+gan_ant_sate;
                    a = "T - S"
                else
                    pire = app.PotenciaEditField.Value+app.OBEditField_2.Value+gan_ant_sate;
                    a = "T - L"
                end
            end
            
            pire
            tipo_pote
            tipo_acceso
            class(tipo_pote)    
            app.ResultCnUp.Value= cn_up( pire,app.UplinkTotalEditField.Value,...
                app.GTsatEditField.Value, app.BwsistEditField.Value);
        end

        % Button pushed function: CalcularCnD
        function CalcularCnDButtonPushed(app, event)
            tipo_acceso = app.AccesomultipleSwitch.Value;
            tipo_pote = app.TipoPotenciaSwitch_2.Value;
            gan = ganancia_antena(app.fr_d, app.nETEditField.Value, app.DiametroETEditField.Value)
            app.ganancia_et = gan;
            
            if  strcmp( tipo_acceso, 'FDMA')
                if strcmp(tipo_pote, 'Saturacion')   
                    pire = app.Potencia_dEditField.Value-app.OBEditField.Value+app.ganancia_et
                    a = "F - S"
                else
                    pire = app.Potencia_dEditField.Value+app.ganancia_et
                    a = "F - L"
                end
            else
                if strcmp(tipo_pote, 'Saturacion')
                    pire = app.Potencia_dEditField.Value+app.ganancia_et
                    a = "T - S"
                else
                    pire = app.Potencia_dEditField.Value+app.OBEditField.Value+app.ganancia_et
                    a = "T - L"
                end
            end
            
            app.pire_d =pire;
            app.UplinkTotalEditField.Value
            app.ResultGT.Value
            app.BwsistEditField.Value
            app.ResultCnDField.Value= cn_up(pire,app.UplinkTotalEditField.Value,...
                app.ResultGT.Value, app.BwsistEditField.Value);
        end

        % Value changed function: BandaDropDown
        function BandaDropDownValueChanged(app, event)
            bandaD = app.BandaDropDown.Value;
            if bandaD == 'C'
                app.fr_up = 6; 
                app.fr_d = 4;                
            
            elseif bandaD == 'Ku'
                app.fr_up = 14; 
                app.fr_d = 12;
            
            elseif bandaD == 'Ka'
                app.fr_up = 30; 
                app.fr_d = 20;
            
            elseif bandaD == 'L'
                app.fr_up = 1.6; 
                app.fr_d = 1.5;
            end
        end

        % Button pushed function: FigH0Button
        function FigH0ButtonPushed(app, event)
            %[corn_rgb] = imread('h0.jpg',2);
            imshow('h0.jpg');
        end

        % Callback function
        function TablaKyAButtonPushed(app, event)
            imshow('kyalfa.png');
        end

        % Button pushed function: ITUP837Button
        function ITUP837ButtonPushed(app, event)
            imshow('R001.png');
        end

        % Button pushed function: FigTempBrillButton
        function FigTempBrillButtonPushed(app, event)
            imshow('temp_brillo.png');
        end

        % Button pushed function: CalcularCnToButton
        function CalcularCnToButtonPushed(app, event)
            app.ResultCnTEditField.Value = cntotal(app.ResultCnDField.Value,...
                app.ResultCnUp.Value);
        end

        % Button pushed function: CalcularRB
        function CalcularRBButtonPushed(app, event)
            RelB = app.RelacinBEditField.Value;
            RelCW = app.RelacinCWEditField.Value;
            W = app.BwsistEditField.Value;
            
            Result = RelB + RelCW + 10*log10(W);
            
            app.ResRb.Value = Result;
        end

        % Button pushed function: CalcularRp
        function CalcularRpButtonPushed(app, event)
            app.ResRp.Value = app.pire_d -app.ptotal.Value + app.ResultGT.Value-...
                app.EbNoEditField.Value - app.MargenDesvEditField.Value -10*log10(1.3806 * (10^-23));
        end

        % Value changed function: pgases
        function pgasesValueChanged(app, event)
            value = app.pgases.Value;
            
        end

        % Value changed function: GasesUplinkEditField
        function GasesUplinkEditFieldValueChanged(app, event)
            value = app.GasesUplinkEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsMedicalSurveyUIFigure = uifigure('Visible', 'off');
            app.PatientsMedicalSurveyUIFigure.Name = 'SIMULADOR SATELITAL';
            app.PatientsMedicalSurveyUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.PatientsMedicalSurveyUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsMedicalSurveyUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'GRUPO SPUTNIK';
            app.ValoresdeReferenciaLabel = uilabel(app.LeftPanel);
            app.ValoresdeReferenciaLabel.Text = 'Valores de Referencia:';
            app.FijarDatosButton = uibutton(app.LeftPanel, 'push');
            app.FijarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @FijarDatosButtonPushed, true);
            app.FijarDatosButton.Text = 'Fijar Datos';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'test.PNG';
            app.LatitudEditFieldLabel = uilabel(app.LeftPanel);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.LatitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatitudEditField.Limits = [-90 90];
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '°';
            app.ElevacionEditFieldLabel = uilabel(app.LeftPanel);
            app.ElevacionEditFieldLabel.Text = 'Elevacion';
            app.ElevacionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ElevacionEditField.Limits = [0 90];
            app.Label_14 = uilabel(app.LeftPanel);
            app.Label_14.Text = '°';
            app.BandaDropDownLabel = uilabel(app.LeftPanel);
            app.BandaDropDownLabel.Text = 'Banda';
            app.BandaDropDown = uidropdown(app.LeftPanel);
            app.BandaDropDown.Items = {'', 'C', 'Ku', 'Ka', 'L'};
            app.BandaDropDown.ValueChangedFcn = createCallbackFcn(app, @BandaDropDownValueChanged, true);
            app.BandaDropDown.Value = '';
            app.AlturahsEditFieldLabel = uilabel(app.LeftPanel);
            app.AlturahsEditFieldLabel.Text = 'Altura (hs)';
            app.AlturahsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AlturahsEditField.Limits = [0 Inf];
            app.KmLabel_2 = uilabel(app.LeftPanel);
            app.KmLabel_2.Text = 'Km';
            app.DiametroETEditFieldLabel = uilabel(app.LeftPanel);
            app.DiametroETEditFieldLabel.Text = 'Diametro ET';
            app.DiametroETEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DiametroETEditField.Limits = [0 Inf];
            app.nETEditFieldLabel = uilabel(app.LeftPanel);
            app.nETEditFieldLabel.Text = 'n ET';
            app.nETEditField = uieditfield(app.LeftPanel, 'numeric');
            app.nETEditField.Limits = [0 1];
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.PerdidasTab = uitab(app.TabGroup3);
            app.PerdidasTab.Title = 'Perdidas';
            app.TabGroup4 = uitabgroup(app.PerdidasTab);
            app.BasicasTab = uitab(app.TabGroup4);
            app.BasicasTab.Title = 'Basicas';
            app.CalcularButton_2 = uibutton(app.BasicasTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.libre = uieditfield(app.BasicasTab, 'numeric');
            app.dBLabel_4 = uilabel(app.BasicasTab);
            app.dBLabel_4.Text = 'dB';
            app.Lfs_downEditFieldLabel = uilabel(app.BasicasTab);
            app.Lfs_downEditFieldLabel.Text = 'Lfs_down';
            app.Lfs_downEditField = uieditfield(app.BasicasTab, 'numeric');
            app.Lfs_upLabel = uilabel(app.BasicasTab);
            app.Lfs_upLabel.Text = 'Lfs_up';
            app.dBLabel_11 = uilabel(app.BasicasTab);
            app.dBLabel_11.Text = 'dB';
            app.DistanciaEditFieldLabel = uilabel(app.BasicasTab);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.BasicasTab, 'numeric');
            app.DistanciaEditField.Limits = [0 Inf];
            app.KmLabel = uilabel(app.BasicasTab);
            app.KmLabel.Text = '  Km';
            app.DesapuntamientoTab = uitab(app.TabGroup4);
            app.DesapuntamientoTab.Title = 'Desapuntamiento';
            app.DesapenTxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenTxEditFieldLabel.Text = 'Desap en Tx';
            app.DesapenTxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.DesapenTxEditField.Limits = [0 Inf];
            app.CalcularButton_3 = uibutton(app.DesapuntamientoTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.DesapenRxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenRxEditFieldLabel.Text = 'Desap en Rx';
            app.DesapenRxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.DesapenRxEditField.Limits = [0 Inf];
            app.Ancho3dBEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.Ancho3dBEditFieldLabel.Text = ' Ancho 3dB';
            app.Ancho3dBEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.Ancho3dBEditField.Limits = [0 Inf];
            app.desap = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.dBLabel_5 = uilabel(app.DesapuntamientoTab);
            app.dBLabel_5.Text = 'dB';
            app.Label_5 = uilabel(app.DesapuntamientoTab);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.DesapuntamientoTab);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.DesapuntamientoTab);
            app.Label_7.Text = '°';
            app.GasesTab_2 = uitab(app.TabGroup4);
            app.GasesTab_2.Title = 'Gases';
            app.CalcularButton_4 = uibutton(app.GasesTab_2, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.pgases = uieditfield(app.GasesTab_2, 'numeric');
            app.pgases.ValueChangedFcn = createCallbackFcn(app, @pgasesValueChanged, true);
            app.dBLabel_6 = uilabel(app.GasesTab_2);
            app.dBLabel_6.Text = 'dB';
            app.DensidaddelAguaEditFieldLabel = uilabel(app.GasesTab_2);
            app.DensidaddelAguaEditFieldLabel.Text = 'Densidad del Agua';
            app.DensidadEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.DensidadEditField.Limits = [0 Inf];
            app.DensidadEditField.Value = 7.5;
            app.gm3Label_2 = uilabel(app.GasesTab_2);
            app.gm3Label_2.Text = '  g/m^3';
            app.UplinkEditFieldLabel = uilabel(app.GasesTab_2);
            app.UplinkEditFieldLabel.Text = 'Uplink';
            app.GasesUplinkEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.GasesUplinkEditField.ValueChangedFcn = createCallbackFcn(app, @GasesUplinkEditFieldValueChanged, true);
            app.DownlinkLabel_2 = uilabel(app.GasesTab_2);
            app.DownlinkLabel_2.Text = 'Downlink';
            app.dBLabel_12 = uilabel(app.GasesTab_2);
            app.dBLabel_12.Text = 'dB';
            app.LluviaTab_2 = uitab(app.TabGroup4);
            app.LluviaTab_2.Title = 'Lluvia';
            app.Label_8 = uilabel(app.LluviaTab_2);
            app.Label_8.Text = '%';
            app.mmhLabel = uilabel(app.LluviaTab_2);
            app.mmhLabel.Text = 'mm/h';
            app.CalcularButton_6 = uibutton(app.LluviaTab_2, 'push');
            app.CalcularButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_6Pushed, true);
            app.CalcularButton_6.Text = 'Calcular';
            app.downplluvia = uieditfield(app.LluviaTab_2, 'numeric');
            app.dBLabel_8 = uilabel(app.LluviaTab_2);
            app.dBLabel_8.Text = 'dB';
            app.Label_13 = uilabel(app.LluviaTab_2);
            app.Label_13.Text = '°';
            app.FigH0Button = uibutton(app.LluviaTab_2, 'push');
            app.FigH0Button.ButtonPushedFcn = createCallbackFcn(app, @FigH0ButtonPushed, true);
            app.FigH0Button.Text = 'Fig. H0';
            app.PBajdaLabel = uilabel(app.LluviaTab_2);
            app.PBajdaLabel.Text = 'P. Bajda';
            app.Up_plluvia = uieditfield(app.LluviaTab_2, 'numeric');
            app.dBLabel_10 = uilabel(app.LluviaTab_2);
            app.dBLabel_10.Text = 'dB';
            app.PSubidaLabel = uilabel(app.LluviaTab_2);
            app.PSubidaLabel.Text = 'P. Subida';
            app.ITUP837Button = uibutton(app.LluviaTab_2, 'push');
            app.ITUP837Button.ButtonPushedFcn = createCallbackFcn(app, @ITUP837ButtonPushed, true);
            app.ITUP837Button.Text = 'ITU P.837';
            app.h0EditFieldLabel = uilabel(app.LluviaTab_2);
            app.h0EditFieldLabel.Text = 'h0';
            app.h0EditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.h0EditField.Limits = [0 Inf];
            app.IndiponibilidadpEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IndiponibilidadpEditFieldLabel.Text = 'Indiponibilidad (p)';
            app.IndiponibilidadpEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.IndiponibilidadpEditField.Limits = [0 100];
            app.IntensidadLluviaEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IntensidadLluviaEditFieldLabel.Text = 'Intensidad Lluvia';
            app.IntensidadLluviaEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.LongitudEditFieldLabel = uilabel(app.LluviaTab_2);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.LongitudEditField.Limits = [-180 180];
            app.PolarizacinDropDownLabel = uilabel(app.LluviaTab_2);
            app.PolarizacinDropDownLabel.Text = 'Polarización';
            app.PolarizacinDropDown = uidropdown(app.LluviaTab_2);
            app.PolarizacinDropDown.Items = {'Horizontal', 'Vertical'};
            app.PolarizacinDropDown.Value = 'Horizontal';
            app.OtrasPerdTab = uitab(app.TabGroup4);
            app.OtrasPerdTab.Title = 'Otras Perd';
            app.ResultOtrasper = uieditfield(app.OtrasPerdTab, 'numeric');
            app.CalcularOtrasper = uibutton(app.OtrasPerdTab, 'push');
            app.CalcularOtrasper.ButtonPushedFcn = createCallbackFcn(app, @CalcularOtrasperButtonPushed, true);
            app.CalcularOtrasper.Text = 'Calcular';
            app.CentelleoLabel = uilabel(app.OtrasPerdTab);
            app.CentelleoLabel.Text = 'Centelleo:';
            app.UplinkEditFieldLabel_2 = uilabel(app.OtrasPerdTab);
            app.UplinkEditFieldLabel_2.Text = 'Uplink';
            app.CentelleoUplinkEditField = uieditfield(app.OtrasPerdTab, 'numeric');
            app.DownlinkLabel_3 = uilabel(app.OtrasPerdTab);
            app.DownlinkLabel_3.Text = 'Downlink';
            app.TotalTab_2 = uitab(app.TabGroup4);
            app.TotalTab_2.Title = 'Total';
            app.CalcularButton_5 = uibutton(app.TotalTab_2, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_5Pushed, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.ptotal = uieditfield(app.TotalTab_2, 'numeric');
            app.dBLabel_7 = uilabel(app.TotalTab_2);
            app.dBLabel_7.Text = 'dB';
            app.UplinkEditFieldLabel_3 = uilabel(app.TotalTab_2);
            app.UplinkEditFieldLabel_3.Text = 'Uplink';
            app.UplinkTotalEditField = uieditfield(app.TotalTab_2, 'numeric');
            app.DownlinkLabel_4 = uilabel(app.TotalTab_2);
            app.DownlinkLabel_4.Text = 'Downlink';
            app.RuidoTab = uitab(app.TabGroup3);
            app.RuidoTab.Title = 'Ruido';
            app.TabGroup5 = uitabgroup(app.RuidoTab);
            app.AntenaTab = uitab(app.TabGroup5);
            app.AntenaTab.Title = 'Antena';
            app.Switch = uiswitch(app.AntenaTab, 'slider');
            app.Switch.Items = {'Con Lluvia', 'Cielo Despejado'};
            app.Switch.Value = 'Con Lluvia';
            app.CalcularButton_8 = uibutton(app.AntenaTab, 'push');
            app.CalcularButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_8Pushed, true);
            app.CalcularButton_8.Text = 'Calcular';
            app.res_antena = uieditfield(app.AntenaTab, 'numeric');
            app.TgEditFieldLabel = uilabel(app.AntenaTab);
            app.TgEditFieldLabel.Text = 'Tg';
            app.TgEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TgEditField.Limits = [0 Inf];
            app.TgEditField.Value = 290;
            app.GlsEditFieldLabel = uilabel(app.AntenaTab);
            app.GlsEditFieldLabel.Text = 'Gls';
            app.GlsEditField = uieditfield(app.AntenaTab, 'numeric');
            app.GlsEditField.Limits = [0 Inf];
            app.TskyEditFieldLabel = uilabel(app.AntenaTab);
            app.TskyEditFieldLabel.Text = 'Tsky';
            app.TskyEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TskyEditField.Limits = [0 Inf];
            app.TskyEditField.Value = 17;
            app.TatmEditFieldLabel = uilabel(app.AntenaTab);
            app.TatmEditFieldLabel.Text = 'Tatm';
            app.TatmEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TatmEditField.Limits = [0 Inf];
            app.TatmEditField.Value = 270;
            app.TwacoploEditFieldLabel = uilabel(app.AntenaTab);
            app.TwacoploEditFieldLabel.Text = 'Tw(acoplo)';
            app.TwacoploEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TwacoploEditField.Limits = [0 Inf];
            app.wacoploEditFieldLabel = uilabel(app.AntenaTab);
            app.wacoploEditFieldLabel.Text = 'w(acoplo)';
            app.wacoploEditField = uieditfield(app.AntenaTab, 'numeric');
            app.wacoploEditField.Limits = [0 Inf];
            app.ReceptorTab = uitab(app.TabGroup5);
            app.ReceptorTab.Title = 'Receptor';
            app.ValoresLabel = uilabel(app.ReceptorTab);
            app.ValoresLabel.Text = 'Valores';
            app.Valores_g = uieditfield(app.ReceptorTab, 'text');
            app.EjsillggLabel = uilabel(app.ReceptorTab);
            app.EjsillggLabel.Text = 'Ej: si [l l g g]';
            app.CalcularButton_7 = uibutton(app.ReceptorTab, 'push');
            app.CalcularButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_7Pushed, true);
            app.CalcularButton_7.Text = 'Calcular';
            app.ResultadoRuido = uieditfield(app.ReceptorTab, 'numeric');
            app.KLabel = uilabel(app.ReceptorTab);
            app.KLabel.Text = '°K';
            app.OrdenEditFieldLabel = uilabel(app.ReceptorTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.Orden = uieditfield(app.ReceptorTab, 'text');
            app.TemperaturaEditField_3Label = uilabel(app.ReceptorTab);
            app.TemperaturaEditField_3Label.Text = 'Temperatura';
            app.TemperaturaRuido = uieditfield(app.ReceptorTab, 'text');
            app.Siaplica2sinoescribir0Label = uilabel(app.ReceptorTab);
            app.Siaplica2sinoescribir0Label.Text = '(Si aplica *[2], sino escribir 0)';
            app.Label_11 = uilabel(app.ReceptorTab);
            app.Label_11.Text = 'Escriba en orden y separado con espacio, los valores correspondientes';
            app.Ej12151Label = uilabel(app.ReceptorTab);
            app.Ej12151Label.Text = 'Ej: [1.2 1.5 ...] *[1]';
            app.TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel = uilabel(app.ReceptorTab);
            app.TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel.Text = '[1]: Todos los valores deben estar en sus propios términos, y en veces.';
            app.AplicacuandoenelordenexistegLabel = uilabel(app.ReceptorTab);
            app.AplicacuandoenelordenexistegLabel.Text = '[2]: Aplica cuando en el orden existe ''g'' ';
            app.SiesgelvaloresenglomismoparaLNohacerconversionesLabel = uilabel(app.ReceptorTab);
            app.SiesgelvaloresenglomismoparaLNohacerconversionesLabel.Text = 'Si es g el valor es en g, lo mismo para L. No hacer conversiones.';
            app.RuidoSistemaTab = uitab(app.TabGroup5);
            app.RuidoSistemaTab.Title = 'Ruido Sistema';
            app.TemperaturadeBrilloEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.TemperaturadeBrilloEditFieldLabel.Text = 'Temperatura de Brillo';
            app.TemperaturadeBrilloEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.TemperaturadeBrilloEditField.Limits = [0 Inf];
            app.TemperaturadeBrilloEditField.Value = 290;
            app.CalcularButton_9 = uibutton(app.RuidoSistemaTab, 'push');
            app.CalcularButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_9Pushed, true);
            app.CalcularButton_9.Text = 'Calcular';
            app.EnlacedeSubidaEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.EnlacedeSubidaEditFieldLabel.Text = 'Enlace de Subida';
            app.EnlacedeSubidaEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.EnlacedeBajadaEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.EnlacedeBajadaEditFieldLabel.Text = 'Enlace de Bajada';
            app.EnlacedeBajadaEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.FigTempBrillButton = uibutton(app.RuidoSistemaTab, 'push');
            app.FigTempBrillButton.ButtonPushedFcn = createCallbackFcn(app, @FigTempBrillButtonPushed, true);
            app.FigTempBrillButton.Text = 'Fig. Temp Brill';
            app.BackoffTab = uitab(app.TabGroup5);
            app.BackoffTab.Title = 'Backoff';
            app.Switch_2 = uiswitch(app.BackoffTab, 'slider');
            app.Switch_2.Items = {'TDMA', 'FDMA'};
            app.Switch_2.Value = 'TDMA';
            app.CalcularButton_10 = uibutton(app.BackoffTab, 'push');
            app.CalcularButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_10Pushed, true);
            app.CalcularButton_10.Text = 'Calcular';
            app.PireinEditFieldLabel = uilabel(app.BackoffTab);
            app.PireinEditFieldLabel.Text = 'Pire in';
            app.PireinEditField = uieditfield(app.BackoffTab, 'numeric');
            app.PireoutEditFieldLabel = uilabel(app.BackoffTab);
            app.PireoutEditFieldLabel.Text = 'Pire out';
            app.PireoutEditField = uieditfield(app.BackoffTab, 'numeric');
            app.IBEditFieldLabel = uilabel(app.BackoffTab);
            app.IBEditFieldLabel.Text = 'IB';
            app.IBEditField = uieditfield(app.BackoffTab, 'numeric');
            app.OBEditFieldLabel = uilabel(app.BackoffTab);
            app.OBEditFieldLabel.Text = 'OB';
            app.OBEditField = uieditfield(app.BackoffTab, 'numeric');
            app.pireinsat = uieditfield(app.BackoffTab, 'numeric');
            app.pireoutsat = uieditfield(app.BackoffTab, 'numeric');
            app.PireinsatLabel = uilabel(app.BackoffTab);
            app.PireinsatLabel.Text = 'Pire in sat';
            app.PireoutsatLabel = uilabel(app.BackoffTab);
            app.PireoutsatLabel.Text = 'Pire out sat';
            app.DeEntradaLabel = uilabel(app.BackoffTab);
            app.DeEntradaLabel.Text = 'De Entrada';
            app.dBmLabel = uilabel(app.BackoffTab);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.BackoffTab);
            app.dBmLabel_2.Text = 'dBm';
            app.ConPireptxgtxLabel = uilabel(app.BackoffTab);
            app.ConPireptxgtxLabel.Text = 'Con Pire=ptx*gtx';
            app.GTTab = uitab(app.TabGroup5);
            app.GTTab.Title = 'G/T';
            app.CalcularGT = uibutton(app.GTTab, 'push');
            app.CalcularGT.ButtonPushedFcn = createCallbackFcn(app, @CalcularGTPushed, true);
            app.CalcularGT.Text = 'Calcular';
            app.ResultGT = uieditfield(app.GTTab, 'numeric');
            app.dBLabel_9 = uilabel(app.GTTab);
            app.dBLabel_9.Text = 'dB';
            app.CNTab = uitab(app.TabGroup3);
            app.CNTab.Title = 'C/N';
            app.TabGroup6 = uitabgroup(app.CNTab);
            app.UpLinkTab = uitab(app.TabGroup6);
            app.UpLinkTab.Title = 'UpLink';
            app.CalcularCNUp = uibutton(app.UpLinkTab, 'push');
            app.CalcularCNUp.ButtonPushedFcn = createCallbackFcn(app, @CalcularCNUpButtonPushed, true);
            app.CalcularCNUp.Text = 'Calcular';
            app.ResultCnUp = uieditfield(app.UpLinkTab, 'numeric');
            app.PotenciaEditFieldLabel = uilabel(app.UpLinkTab);
            app.PotenciaEditFieldLabel.Text = 'Potencia';
            app.PotenciaEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.OBEditField_2Label = uilabel(app.UpLinkTab);
            app.OBEditField_2Label.Text = 'OB';
            app.OBEditField_2 = uieditfield(app.UpLinkTab, 'numeric');
            app.OBEditField_2.Limits = [0 Inf];
            app.GTsatEditFieldLabel = uilabel(app.UpLinkTab);
            app.GTsatEditFieldLabel.Text = 'GT sat';
            app.GTsatEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.dBwLabel = uilabel(app.UpLinkTab);
            app.dBwLabel.Text = 'dBw';
            app.nEditFieldLabel = uilabel(app.UpLinkTab);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.nEditField.Limits = [0 1];
            app.DiametroEditFieldLabel = uilabel(app.UpLinkTab);
            app.DiametroEditFieldLabel.Text = 'Diametro';
            app.DiametroEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.DiametroEditField.Limits = [0 Inf];
            app.dBLabel_13 = uilabel(app.UpLinkTab);
            app.dBLabel_13.Text = 'dB';
            app.mLabel = uilabel(app.UpLinkTab);
            app.mLabel.Text = 'm';
            app.BwsistEditFieldLabel = uilabel(app.UpLinkTab);
            app.BwsistEditFieldLabel.Text = 'Bw sist';
            app.BwsistEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.BwsistEditField.Limits = [0 Inf];
            app.AccesomultipleSwitchLabel = uilabel(app.UpLinkTab);
            app.AccesomultipleSwitchLabel.Text = 'Acceso multiple';
            app.AccesomultipleSwitch = uiswitch(app.UpLinkTab, 'slider');
            app.AccesomultipleSwitch.Items = {'TDMA', 'FDMA'};
            app.AccesomultipleSwitch.Value = 'TDMA';
            app.TipoPotenciaSwitchLabel = uilabel(app.UpLinkTab);
            app.TipoPotenciaSwitchLabel.Text = 'Tipo Potencia';
            app.TipoPotenciaSwitch = uiswitch(app.UpLinkTab, 'slider');
            app.TipoPotenciaSwitch.Items = {'Saturacion', 'Lineal'};
            app.TipoPotenciaSwitch.Value = 'Saturacion';
            app.DownlinkTab = uitab(app.TabGroup6);
            app.DownlinkTab.Title = 'Downlink';
            app.CalcularCnD = uibutton(app.DownlinkTab, 'push');
            app.CalcularCnD.ButtonPushedFcn = createCallbackFcn(app, @CalcularCnDButtonPushed, true);
            app.CalcularCnD.Text = 'Calcular';
            app.ResultCnDField = uieditfield(app.DownlinkTab, 'numeric');
            app.dBwLabel_2 = uilabel(app.DownlinkTab);
            app.dBwLabel_2.Text = 'dBw';
            app.dBLabel_14 = uilabel(app.DownlinkTab);
            app.dBLabel_14.Text = 'dB';
            app.PotenciaEditField_2Label = uilabel(app.DownlinkTab);
            app.PotenciaEditField_2Label.Text = 'Potencia';
            app.Potencia_dEditField = uieditfield(app.DownlinkTab, 'numeric');
            app.OBEditField_3Label = uilabel(app.DownlinkTab);
            app.OBEditField_3Label.Text = 'OB';
            app.OB_dEditField = uieditfield(app.DownlinkTab, 'numeric');
            app.TipoPotenciaSwitch_2Label = uilabel(app.DownlinkTab);
            app.TipoPotenciaSwitch_2Label.Text = 'Tipo Potencia';
            app.TipoPotenciaSwitch_2 = uiswitch(app.DownlinkTab, 'slider');
            app.TipoPotenciaSwitch_2.Items = {'Saturación', 'Lineal'};
            app.TipoPotenciaSwitch_2.Value = 'Saturación';
            app.TotalTab_3 = uitab(app.TabGroup6);
            app.TotalTab_3.Title = 'Total';
            app.ResultCnTEditField = uieditfield(app.TotalTab_3, 'numeric');
            app.CalcularCnToButton = uibutton(app.TotalTab_3, 'push');
            app.CalcularCnToButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularCnToButtonPushed, true);
            app.CalcularCnToButton.Text = 'Calcular';
            app.CapacidadTab = uitab(app.TabGroup3);
            app.CapacidadTab.Title = 'Capacidad';
            app.TabGroup7 = uitabgroup(app.CapacidadTab);
            app.RbTab = uitab(app.TabGroup7);
            app.RbTab.Title = 'Rb';
            app.dBLabel_15 = uilabel(app.RbTab);
            app.dBLabel_15.Text = '[dB]';
            app.dBLabel_16 = uilabel(app.RbTab);
            app.dBLabel_16.Text = '[dB]';
            app.CalcularRB = uibutton(app.RbTab, 'push');
            app.CalcularRB.ButtonPushedFcn = createCallbackFcn(app, @CalcularRBButtonPushed, true);
            app.CalcularRB.Text = 'Calcular';
            app.ResRb = uieditfield(app.RbTab, 'numeric');
            app.Label_15 = uilabel(app.RbTab);
            app.Label_15.Text = '_____________________________________________________';
            app.VelocidaddelEnlaceLabel = uilabel(app.RbTab);
            app.VelocidaddelEnlaceLabel.Text = 'Velocidad del Enlace =';
            app.RelacinBEditFieldLabel = uilabel(app.RbTab);
            app.RelacinBEditFieldLabel.Text = 'Relación B =';
            app.RelacinBEditField = uieditfield(app.RbTab, 'numeric');
            app.RelacinCWEditFieldLabel = uilabel(app.RbTab);
            app.RelacinCWEditFieldLabel.Text = 'Relación CW =';
            app.RelacinCWEditField = uieditfield(app.RbTab, 'numeric');
            app.RpTab = uitab(app.TabGroup7);
            app.RpTab.Title = 'Rp';
            app.dBLabel_17 = uilabel(app.RpTab);
            app.dBLabel_17.Text = '[dB]';
            app.dBLabel_18 = uilabel(app.RpTab);
            app.dBLabel_18.Text = '[dB]';
            app.CalcularRp = uibutton(app.RpTab, 'push');
            app.CalcularRp.ButtonPushedFcn = createCallbackFcn(app, @CalcularRpButtonPushed, true);
            app.CalcularRp.Text = 'Calcular';
            app.ResRp = uieditfield(app.RpTab, 'numeric');
            app.Label_16 = uilabel(app.RpTab);
            app.Label_16.Text = '_____________________________________________________';
            app.EbNoEditFieldLabel = uilabel(app.RpTab);
            app.EbNoEditFieldLabel.Text = 'Eb/No =';
            app.EbNoEditField = uieditfield(app.RpTab, 'numeric');
            app.MLabel = uilabel(app.RpTab);
            app.MLabel.Text = 'M =';
            app.MargenDesvEditField = uieditfield(app.RpTab, 'numeric');
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.PatientsMedicalSurveyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsMedicalSurveyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsMedicalSurveyUIFigure)
        end
    end
end